classdef app_dialogbox_resultado < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ResultadoUIFigure            matlab.ui.Figure
        AnemiaEditFieldLabel         matlab.ui.control.Label
        AnemiaEditField              matlab.ui.control.EditField
        OkButton                     matlab.ui.control.Button
        MorfologiaEditFieldLabel     matlab.ui.control.Label
        MorfologiaEditField          matlab.ui.control.EditField
        CromaticidadeEditFieldLabel  matlab.ui.control.Label
        CromaticidadeEditField       matlab.ui.control.EditField
        ResultadosLabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, result_anemia, result_morfologia, result_cromaticidade)
             app.CallingApp = mainapp;
             app.AnemiaEditField.Value = result_anemia;
             app.MorfologiaEditField.Value = result_morfologia;
             app.CromaticidadeEditField.Value = result_cromaticidade;
        end

        % Callback function: OkButton, ResultadoUIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.TestarButton.Enable = 'on';
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ResultadoUIFigure = uifigure('Visible', 'off');
            app.ResultadoUIFigure.Name = 'Resultado';
            app.ResultadoUIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AnemiaEditFieldLabel = uilabel(app.ResultadoUIFigure);
            app.AnemiaEditFieldLabel.Text = 'Anemia';
            app.AnemiaEditField = uieditfield(app.ResultadoUIFigure, 'text');
            app.OkButton = uibutton(app.ResultadoUIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OkButton.Text = 'Ok';
            app.MorfologiaEditFieldLabel = uilabel(app.ResultadoUIFigure);
            app.MorfologiaEditFieldLabel.Text = 'Morfologia';
            app.MorfologiaEditField = uieditfield(app.ResultadoUIFigure, 'text');
            app.CromaticidadeEditFieldLabel = uilabel(app.ResultadoUIFigure);
            app.CromaticidadeEditFieldLabel.Text = 'Cromaticidade';
            app.CromaticidadeEditField = uieditfield(app.ResultadoUIFigure, 'text');
            app.ResultadosLabel = uilabel(app.ResultadoUIFigure);
            app.ResultadosLabel.Text = 'Resultados';
            % Show the figure after all components are created
            app.ResultadoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_dialogbox_resultado(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ResultadoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ResultadoUIFigure)
        end
    end
end