classdef app_teste < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Eritroteste                   matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        IncioTab                      matlab.ui.container.Tab
        TestarButton                  matlab.ui.control.Button
        ERITROTESTELabel              matlab.ui.control.Label
        SexoSwitchLabel               matlab.ui.control.Label
        SexoSwitch                    matlab.ui.control.Switch
        IdadeEditFieldLabel           matlab.ui.control.Label
        IdadeEditField                matlab.ui.control.NumericEditField
        HemciasEditFieldLabel         matlab.ui.control.Label
        HemciasEditField              matlab.ui.control.NumericEditField
        HemoglobinaEditFieldLabel     matlab.ui.control.Label
        HemoglobinaEditField          matlab.ui.control.NumericEditField
        HematcritoEditFieldLabel      matlab.ui.control.Label
        HematcritoEditField           matlab.ui.control.NumericEditField
        VCMEditFieldLabel             matlab.ui.control.Label
        VCMEditField                  matlab.ui.control.NumericEditField
        HCMEditFieldLabel             matlab.ui.control.Label
        HCMEditField                  matlab.ui.control.NumericEditField
        CHCMEditFieldLabel            matlab.ui.control.Label
        CHCMEditField                 matlab.ui.control.NumericEditField
        MilhesmLabel                  matlab.ui.control.Label
        GDLLabel                      matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        FLLabel                       matlab.ui.control.Label
        PGLabel                       matlab.ui.control.Label
        GDLLabel_2                    matlab.ui.control.Label
        anosLabel                     matlab.ui.control.Label
        Label_6                       matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        ArtigosTab                    matlab.ui.container.Tab
        Label_3                       matlab.ui.control.Label
        VisualizarButton              matlab.ui.control.Button
        Label_4                       matlab.ui.control.Label
        VisualizarButton_2            matlab.ui.control.Button
        AnaisdeCongressoLabel         matlab.ui.control.Label
        ERMAC2018Label_2              matlab.ui.control.Label
        ENMC2019Label_2               matlab.ui.control.Label
        AvisoLegalTab                 matlab.ui.container.Tab
        TextArea                      matlab.ui.control.TextArea
        TermosdeutilizaoLabel         matlab.ui.control.Label
        CrditosTab                    matlab.ui.container.Tab
        AplicativoLabel               matlab.ui.control.Label
        ClassificaoeconsultoriaLabel  matlab.ui.control.Label
        LucasKortLabel                matlab.ui.control.Label
        DrHelderFigueiraLabel         matlab.ui.control.Label
        RedesNeuraisCdigoetreinamentoLabel  matlab.ui.control.Label
        LucasKortMrcioKortAndressaAlvesLabel  matlab.ui.control.Label
        Image_2                       matlab.ui.control.Image
        ERITROTESTELabel_2            matlab.ui.control.Label
    end

    
    properties (Access = private)
        DialogApp % Description
    end
    
     methods (Access = private)      
        function resul_exame_morfologia = rede_tipos_morfologia(app, vetor_exame_completo)
            load rede_anemia_morfologia.mat net;   %Carregar a última rede treinada p/ morfologia
            resul_exame_morfologia = sim(net, vetor_exame_completo); %Executar a rede
            clear net %Evitar conflitos entre redes diferentes
        end
        
        function resul_exame_cromaticidade = rede_tipos_cromaticidade(app, vetor_exame_completo)
            load rede_anemia_cromaticidade.mat net  %Carregar a última rede treinada p/ cromaticidade
            resul_exame_cromaticidade  = sim(net, vetor_exame_completo); %Executar a rede
            clear net %Evitar conflitos entre redes diferentes
        end
        
         function result_morfologia_apres = funcao_resultados_morfologia(app,resul_exame_morfologia)
            ref = max(resul_exame_morfologia);
            if resul_exame_morfologia(1) == ref  %Descobrir o maior valor apresentado pela rede para depois descobrir a posição
                result_morfologia_apres = 'Macrocítica';
            elseif resul_exame_morfologia(2) == ref
                result_morfologia_apres = 'Normocítica';
            else
                result_morfologia_apres = 'Microcítica';
            end
        end
         function result_cromaticidade_apres = funcao_resultados_cromaticidade(app,resul_exame_cromaticidade)
            ref = max(resul_exame_cromaticidade);
            if resul_exame_cromaticidade(1) == ref  %Descobrir o maior valor apresentado pela rede para depois descobrir a posição
                result_cromaticidade_apres = 'Hipocrômica';
            elseif resul_exame_cromaticidade(2) == ref
                result_cromaticidade_apres = 'Normocrômica';
            else
                result_cromaticidade_apres = 'Hipercrômica';
            end
         end
%         function output = uploadToDropbox(app, dropboxAccessToken,varargin)  %Faz upload dos exames e resultados para auditoria.
%             % uploadToDropbox uses the dropboxAccessToken to upload a file to dropbox.
%             % If a fully qualified filename is specified as a second parameter, the 
%             % file will be uploaded. If no filename is provided, a dialog is presented 
%             % to allow you to select the file to be uploaded to Dropbox.
%             %
%             % To generate a Dropbox access token
%             %* Go to www.dropbox.com/developers/apps in a web browser
%             %* Click on "Create app"
%             %* Select "Dropbox API" and "App folder" for options (1) and (2)
%             %* Specify the name of the folder you want your files to show up in
%             %* Click on the "Generate Access Token" on the page and note down the access token
%             % Copyright 2016 The MathWorks, Inc.
% %             % Check to input arguments
% %             narginchk(1,2)
% %             % If no file specified, pop up the dialog for the user to select one
% %             if isequal(nargin,1)
% %                 [dataFileName, dataFilePath] = uigetfile('*.*','Select file to upload to Dropbox');
% %                 dataFile = fullfile(dataFilePath, dataFileName);
% %             elseif isequal(nargin,2)
% %                 dataFile = varargin{1};
% %             end
% %             % Check if input file exists
% %             if ~exist(dataFile,'file')
% %                 throw(MException('uploadToDropbox:fileNotFound','Input file was not found.'));
% %             end
%             dataFile = varargin{1}
%             % Read file contents
%             try
%                 fid = fopen(dataFile, 'r');
%                 data = char(fread(fid)');
%                 fclose(fid);
%             catch someException
%                 throw(addCause(MException('uploadToDropbox:unableToReadFile','Unable to read input file.'),someException));
%             end
%             % Generate the custom header
%             [~,remoteFName, remoteExt] = fileparts(dataFile);
%             headerFields = {'Authorization', ['Bearer ', dropboxAccessToken]};
%             headerFields{2,1} = 'Dropbox-API-Arg';
%             headerFields{2,2} = sprintf('{"path": "/%s%s", "mode": "add", "autorename": true, "mute": false}',remoteFName, remoteExt);
%             headerFields{3,1} = 'Content-Type';
%             headerFields{3,2} = 'application/octet-stream';
%             headerFields = string(headerFields);
%             % Set the options for WEBWRITE
%             opt = weboptions;
%             opt.MediaType = 'application/octet-stream';
%             opt.CharacterEncoding = 'ISO-8859-1';
%             opt.RequestMethod = 'post';
%             opt.HeaderFields = headerFields;
%             % Upload the file
%             try
%                 tempOutput = webwrite('https://content.dropboxapi.com/2/files/upload', data, opt);
%             catch someException
%                 throw(addCause(MException('uploadToDropbox:unableToUploadFile','Unable to upload file.'),someException));
%             end
%             % If user requested output, pass along WEBWRITE output
%             if isequal(nargout,1)
%                 output = tempOutput;
%             end    
%         end
     end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TestarButton
        function TestarButtonPushed(app, event)
            clc;
            idade = app.IdadeEditField.Value;         %Receber parâmetros das lacunas do UI
            hemacias = app.HemciasEditField.Value;
            hemoglobina = app.HemoglobinaEditField.Value;
            hematocrito = app.HematcritoEditField.Value;
            vcm = app.VCMEditField.Value;
            hcm = app.HCMEditField.Value;
            chcm = app.CHCMEditField.Value;
            if app.SexoSwitch.Value == "M"
                sexo = 1;
            else
                sexo = 0;
            end
            vetor_exame_anemia = [sexo; idade; (hematocrito/100); hemoglobina; hemacias];  %Formar vetor de entrada
            load rede_anemia.mat net  %Carregar a rede anemia
            resul_exame_anemia = sim(net, vetor_exame_anemia) %Executar rede anemia
            clear net  %Limpar rede para não dar conflitos
            if resul_exame_anemia > 0.75
                result_anemia_apres = 'Positivo';  %Apresentação resultado anemia
                vetor_exame_completo = [idade; sexo; hemacias; hemoglobina; hematocrito; vcm; hcm; chcm];
                resul_exame_morfologia = rede_tipos_morfologia(app, vetor_exame_completo);
                resul_exame_cromaticidade = rede_tipos_cromaticidade(app, vetor_exame_completo);
                result_morfologia_apres = funcao_resultados_morfologia(app,resul_exame_morfologia);
                result_cromaticidade_apres = funcao_resultados_cromaticidade(app,resul_exame_cromaticidade);
                %Desligar o botão para não abrir multiplas janelas
                app.TestarButton.Enable = 'off';
                %Abrir janela pop-up
                app.DialogApp = app_dialogbox_resultado(app,result_anemia_apres, result_morfologia_apres, result_cromaticidade_apres);
                dados_export=[idade; sexo; hemacias; hemoglobina; hematocrito; vcm; hcm; chcm; resul_exame_anemia; resul_exame_morfologia; resul_exame_cromaticidade];
            else
                result_anemia_apres = 'Negativo';
                %Desligar o botão para não abrir multiplas janelas
                app.TestarButton.Enable = 'off';
                %Abrir janela pop-up
                app.DialogApp = app_dialogbox_apenas_anemia(app,result_anemia_apres);
                dados_export=[idade; sexo; hemacias; hemoglobina; hematocrito; vcm; hcm; chcm; resul_exame_anemia];
            end
            nome_salvamento = strcat(date,'.txt');
            save(fullfile(tempdir, nome_salvamento),'dados_export','-ASCII') %Salvar na pastar arquivos temporários
            %fopen(fullfile(tempdir, nome_salvamento));
            %save(nome_salvamento,'dados_export','-ASCII');
            %Desativado por privacidade
            %dropboxAccessToken = 'A chave entraria aqui'; %Chave de acesso para o dropbox
            %uploadToDropbox(app, dropboxAccessToken,fullfile(tempdir, nome_salvamento))
            
            %Alertas possiveis, mas não usados.
%             fig = uifigure;
%             message = sprintf('Fire hazard! \n Consider reducing temperature.');
%             uialert(fig,message,'Warning',...
%             'Icon','warning');
%              f = msgbox({'Operation'; 'Completed'});
        end

        % Close request function: Eritroteste
        function EritrotesteCloseRequest(app, event)
            delete(app.DialogApp)  %Se eu fechar a janela principal, fecha os dois apps
            delete(app)
        end

        % Button pushed function: VisualizarButton
        function VisualizarButtonPushed(app, event)
            %ERMAC 2018 Link para anais
            web('http://ermac.ufes.br/sites/ermac.ufes.br/files/field/anexo/anais_ermac_0.pdf','-browser');
        end

        % Button pushed function: VisualizarButton_2
        function VisualizarButton_2Pushed(app, event)
            %ENMC 2019 Link para anais
            web('http://enmc.fadepe.org.br/assets/files/Anais_ENMC_2019.pdf','-browser');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Eritroteste = uifigure('Visible', 'off');
            app.Eritroteste.Name = 'Eritroteste';
            app.Eritroteste.CloseRequestFcn = createCallbackFcn(app, @EritrotesteCloseRequest, true);
            app.TabGroup = uitabgroup(app.Eritroteste);
            app.IncioTab = uitab(app.TabGroup);
            app.IncioTab.Title = 'Início';
            app.TestarButton = uibutton(app.IncioTab, 'push');
            app.TestarButton.ButtonPushedFcn = createCallbackFcn(app, @TestarButtonPushed, true);
            app.TestarButton.Text = 'Testar';
            app.ERITROTESTELabel = uilabel(app.IncioTab);
            app.ERITROTESTELabel.Text = ' ERITROTESTE';
            app.SexoSwitchLabel = uilabel(app.IncioTab);
            app.SexoSwitchLabel.Text = 'Sexo';
            app.SexoSwitch = uiswitch(app.IncioTab, 'slider');
            app.SexoSwitch.Items = {'M', 'F'};
            app.SexoSwitch.Value = 'M';
            app.IdadeEditFieldLabel = uilabel(app.IncioTab);
            app.IdadeEditFieldLabel.Text = 'Idade';
            app.IdadeEditField = uieditfield(app.IncioTab, 'numeric');
            app.IdadeEditField.Limits = [0 Inf];
            app.IdadeEditField.ValueDisplayFormat = '%.0f';
            app.HemciasEditFieldLabel = uilabel(app.IncioTab);
            app.HemciasEditFieldLabel.Text = 'Hemácias';
            app.HemciasEditField = uieditfield(app.IncioTab, 'numeric');
            app.HemciasEditField.Limits = [0 Inf];
            app.HemoglobinaEditFieldLabel = uilabel(app.IncioTab);
            app.HemoglobinaEditFieldLabel.Text = 'Hemoglobina';
            app.HemoglobinaEditField = uieditfield(app.IncioTab, 'numeric');
            app.HemoglobinaEditField.Limits = [0 Inf];
            app.HematcritoEditFieldLabel = uilabel(app.IncioTab);
            app.HematcritoEditFieldLabel.Text = 'Hematócrito';
            app.HematcritoEditField = uieditfield(app.IncioTab, 'numeric');
            app.HematcritoEditField.Limits = [0 Inf];
            app.VCMEditFieldLabel = uilabel(app.IncioTab);
            app.VCMEditFieldLabel.Text = 'VCM';
            app.VCMEditField = uieditfield(app.IncioTab, 'numeric');
            app.VCMEditField.Limits = [0 Inf];
            app.HCMEditFieldLabel = uilabel(app.IncioTab);
            app.HCMEditFieldLabel.Text = 'HCM';
            app.HCMEditField = uieditfield(app.IncioTab, 'numeric');
            app.HCMEditField.Limits = [0 Inf];
            app.CHCMEditFieldLabel = uilabel(app.IncioTab);
            app.CHCMEditFieldLabel.Text = 'CHCM';
            app.CHCMEditField = uieditfield(app.IncioTab, 'numeric');
            app.CHCMEditField.Limits = [0 Inf];
            app.MilhesmLabel = uilabel(app.IncioTab);
            app.MilhesmLabel.Text = 'Milhões/m³';
            app.GDLLabel = uilabel(app.IncioTab);
            app.GDLLabel.Text = 'G/DL';
            app.Label_5 = uilabel(app.IncioTab);
            app.Label_5.Text = '%';
            app.FLLabel = uilabel(app.IncioTab);
            app.FLLabel.Text = 'FL';
            app.PGLabel = uilabel(app.IncioTab);
            app.PGLabel.Text = 'PG';
            app.GDLLabel_2 = uilabel(app.IncioTab);
            app.GDLLabel_2.Text = 'G/DL';
            app.anosLabel = uilabel(app.IncioTab);
            app.anosLabel.Text = 'anos';
            app.Label_6 = uilabel(app.IncioTab);
            app.Label_6.Enable = 'off';
            app.Label_6.Text = '1.0';
            app.Image = uiimage(app.IncioTab);
            app.Image.ImageSource = 'Imagem4.png';
            app.ArtigosTab = uitab(app.TabGroup);
            app.ArtigosTab.Title = 'Artigos';
            app.Label_3 = uilabel(app.ArtigosTab);
            app.Label_3.Text = {'Diagnóstico assistido por '; 'inteligência computacional para'; 'identificação de anemias. Pg. 123.'};
            app.VisualizarButton = uibutton(app.ArtigosTab, 'push');
            app.VisualizarButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizarButtonPushed, true);
            app.VisualizarButton.Text = 'Visualizar';
            app.Label_4 = uilabel(app.ArtigosTab);
            app.Label_4.Text = {'Utilização de Redes Neurais '; 'Artificiais na classificação de '; 'anemias. Pg. 1080.'};
            app.VisualizarButton_2 = uibutton(app.ArtigosTab, 'push');
            app.VisualizarButton_2.ButtonPushedFcn = createCallbackFcn(app, @VisualizarButton_2Pushed, true);
            app.VisualizarButton_2.Text = 'Visualizar';
            app.AnaisdeCongressoLabel = uilabel(app.ArtigosTab);
            app.AnaisdeCongressoLabel.Text = 'Anais de Congresso';
            app.ERMAC2018Label_2 = uilabel(app.ArtigosTab);
            app.ERMAC2018Label_2.Text = {'ERMAC 2018'; ''};
            app.ENMC2019Label_2 = uilabel(app.ArtigosTab);
            app.ENMC2019Label_2.Text = 'ENMC 2019';
            app.AvisoLegalTab = uitab(app.TabGroup);
            app.AvisoLegalTab.Title = 'Aviso Legal';
            app.TextArea = uitextarea(app.AvisoLegalTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'1.	Consentimento do usuário'; ''; 'Ao usar nosso Aplicativo, você está consentindo com o processamento de todos os dados informados no ambiente da aplicação. Processamento significa o uso dos dados coletados, em qualquer que seja o dispositivo de execução, incluindo, mas não limitado a coleta, armazenamento, diagnóstico, em atividades que se darão no território da República Federativa do Brasil.'; ''; '2.	Política de retenção de dados'; ''; 'Nós iremos reter todos os dados informados à aplicação por um período indeterminado. NÃO iremos pedir ou coletar qualquer informação que nos permita identificação individual ou de grupos de NENHUMA maneira. Ao usar a aplicação você estará consentindo com nossa Política de Retenção de dados e NÃO poderá de forma alguma, pedir o envio de tais informações e nem que estas sejam apagadas de nossos registros.'};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
            app.TermosdeutilizaoLabel = uilabel(app.AvisoLegalTab);
            app.TermosdeutilizaoLabel.Text = 'Termos de utilização';
            app.CrditosTab = uitab(app.TabGroup);
            app.CrditosTab.Title = 'Créditos';
            app.AplicativoLabel = uilabel(app.CrditosTab);
            app.AplicativoLabel.Text = 'Aplicativo:';
            app.ClassificaoeconsultoriaLabel = uilabel(app.CrditosTab);
            app.ClassificaoeconsultoriaLabel.Text = 'Classificação e consultoria:';
            app.LucasKortLabel = uilabel(app.CrditosTab);
            app.LucasKortLabel.Text = 'Lucas Kort';
            app.DrHelderFigueiraLabel = uilabel(app.CrditosTab);
            app.DrHelderFigueiraLabel.Text = 'Dr. Helder Figueira';
            app.RedesNeuraisCdigoetreinamentoLabel = uilabel(app.CrditosTab);
            app.RedesNeuraisCdigoetreinamentoLabel.Text = {'Redes Neurais'; 'Código e treinamento:'};
            app.LucasKortMrcioKortAndressaAlvesLabel = uilabel(app.CrditosTab);
            app.LucasKortMrcioKortAndressaAlvesLabel.Text = {'Lucas Kort'; 'Márcio Kort'; 'Andressa Alves'};
            app.Image_2 = uiimage(app.CrditosTab);
            app.Image_2.ImageSource = 'Imagem4.png';
            app.ERITROTESTELabel_2 = uilabel(app.CrditosTab);
            app.ERITROTESTELabel_2.Text = ' ERITROTESTE';
            % Show the figure after all components are created
            app.Eritroteste.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_teste

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Eritroteste)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Eritroteste)
        end
    end
end